classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        freqLabel_2            matlab.ui.control.Label
        UITable_2              matlab.ui.control.Table
        amplitudeLabel         matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        RightPanel             matlab.ui.container.Panel
        playButton             matlab.ui.control.Button
        filepathTextArea       matlab.ui.control.TextArea
        filepathTextAreaLabel  matlab.ui.control.Label
        getsampleButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: getsampleButton
        function getsampleButtonPushed(app, event)
            wave = audioread(string(app.filepathTextArea.Value));
            len = length(wave);
            time = floor([0.097, 0.266, 1.767, 2.234, 2.705, 3.146, 3.606, ...
              4.506, 4.521, 5.030, 5.749, 5.978, 7.015, 7.709, ...
              7.924, 8.028, 8.490, 8.959, 9.455, 9.852, 10.125, ...
              10.356, 10.565, 10.822, 11.292, 11.741, 12.284, ...
              12.742, 13.269, 13.758, 14.315, 14.939, 15.432]/16.384*len);
            fs = len/16.384;
            freq = zeros(length(time), 7);
            amp = zeros(length(time), 7);
            for i = 1:length(time)
                if i == 1
                    wave_tmp = wave(1:time(i)-1);
                else 
                    wave_tmp = wave(time(i-1):time(i)-1);
                end
    
            wave_tmp = repmat(wave_tmp, 1000, 1);
            [freq(i,:), amp(i,:)] = analysis_component(wave_tmp, fs);
            end
            app.UITable.Data = amp;
            app.UITable_2.Data = freq;
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            ex_10;
        end

        % Value changed function: filepathTextArea
        function filepathTextAreaValueChanged(app, event)
            value = app.filepathTextArea.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {390, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.amplitudeLabel = uilabel(app.LeftPanel);
            app.amplitudeLabel.Text = 'amplitude';
            app.UITable_2 = uitable(app.LeftPanel);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.freqLabel_2 = uilabel(app.LeftPanel);
            app.freqLabel_2.Text = 'freq';
            app.RightPanel = uipanel(app.GridLayout);
            app.getsampleButton = uibutton(app.RightPanel, 'push');
            app.getsampleButton.ButtonPushedFcn = createCallbackFcn(app, @getsampleButtonPushed, true);
            app.getsampleButton.Text = 'get sample';
            app.filepathTextAreaLabel = uilabel(app.RightPanel);
            app.filepathTextAreaLabel.Text = 'file path';
            app.filepathTextArea = uitextarea(app.RightPanel);
            app.filepathTextArea.ValueChangedFcn = createCallbackFcn(app, @filepathTextAreaValueChanged, true);
            app.playButton = uibutton(app.RightPanel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play 东方红';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end